classdef Data_visualisation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        DensitySliderLabel     matlab.ui.control.Label
        DensitySlider          matlab.ui.control.Slider
        OverlayCavityCheckBox  matlab.ui.control.CheckBox
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DensitySlider
        function DensitySliderValueChanged(app, event)
            temp_value = app.DensitySlider.Value;
            temp_value = temp_value - 1.5;
            temp_value = round(temp_value/0.5)*0.5;
            value = temp_value + 1.5;
            X = importdata('.\Database generation\Results\LAGOS_X.mat');
            Y = importdata('.\Database generation\Results\LAGOS_Y.mat');
            Result_file = ['.\Database generation\Results\LAGOS_GS_5_MD_',num2str(value),'_CD_5_Z.mat'];
            Z = importdata(Result_file);
            
            Contour_levels = [0 0.025 0.05 0.075 0.1 0.125 0.15 0.175 0.2];
            contour(app.UIAxes,X,Y,Z,Contour_levels,'ShowText','on','LabelSpacing',144,'LineWidth',3);
            % grid on
%             title(app.UIAxes,'Signal with cavity - signal without cavity','interpreter','latex')
%             xlabel(app.UIAxes,'x-coordinate [m]','interpreter','latex')
%             ylabel(app.UIAxes,'y-coordinate [m]','interpreter','latex')
            
            %colorbar(app.UIAxes)


            %c = colorbar;
            %c.Label.String = 'Gravity Difference [mGal]';
            %c.Label.Interpreter = 'latex';
            %c.Label.FontSize = 11;

            %app.UIAxes.YLim = [5 25];
        end

        % Value changed function: OverlayCavityCheckBox
        function OverlayCavityCheckBoxValueChanged(app, event)
            value = app.OverlayCavityCheckBox.Value;
            if value == true
                hold(app.UIAxes, 'on')
                Coordinates = Retrieve_coordinates('LAGOS');
                Plot_cavity = scatter(app.UIAxes,Coordinates(:,1),Coordinates(:,2),'k');
                hold(app.UIAxes, 'off')
            else
                hold(app.UIAxes, 'off')
                clear Plot_cavity
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DensitySliderLabel = uilabel(app.LeftPanel);
            app.DensitySliderLabel.Text = 'Density';
            app.DensitySlider = uislider(app.LeftPanel);
            app.DensitySlider.Limits = [1.5 3.5];
            app.DensitySlider.MajorTicks = [1.5 2.5 3.5];
            app.DensitySlider.MajorTickLabels = {'1.5', '2.5', '3.5'};
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @DensitySliderValueChanged, true);
            app.DensitySlider.MinorTicks = [2 3];
            app.DensitySlider.Value = 1.5;
            app.OverlayCavityCheckBox = uicheckbox(app.LeftPanel);
            app.OverlayCavityCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCavityCheckBoxValueChanged, true);
            app.OverlayCavityCheckBox.Text = 'Overlay Cavity';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Signal with cavity - signal without cavity')
            xlabel(app.UIAxes, 'x-coordinate [m]')
            ylabel(app.UIAxes, 'y-coordinate [m]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_visualisation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end