classdef Data_visualisation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DensityLabel                   matlab.ui.control.Label
        DensitySlider                  matlab.ui.control.Slider
        OverlayCavityCheckBox          matlab.ui.control.CheckBox
        SizeMultiplicationLabel        matlab.ui.control.Label
        SizeMultiplicationSlider       matlab.ui.control.Slider
        CavityDepthLabel               matlab.ui.control.Label
        CavityDepthSlider              matlab.ui.control.Slider
        StationSpacingDropDownLabel_2  matlab.ui.control.Label
        AsquaregridCheckBox            matlab.ui.control.CheckBox
        AspiralCheckBox                matlab.ui.control.CheckBox
        StationSpacingDropDownLabel_5  matlab.ui.control.Label
        StationSpacingDropDownLabel    matlab.ui.control.Label
        StationSpacingDropDown         matlab.ui.control.DropDown
        StationSpacingDropDownLabel_6  matlab.ui.control.Label
        NumberofturnsDropDownLabel     matlab.ui.control.Label
        NumberofturnsDropDown          matlab.ui.control.DropDown
        LAGOSCheckBox                  matlab.ui.control.CheckBox
        GALACheckBox                   matlab.ui.control.CheckBox
        JAMEOCheckBox                  matlab.ui.control.CheckBox
        ETNACheckBox                   matlab.ui.control.CheckBox
        NumberofpointsDropDownLabel    matlab.ui.control.Label
        NumberofpointsDropDown         matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        NolinesCheckBox                matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        SSF % Station resolution setting
        bool_use_squares 
        bool_use_spiral 
        Plot_cavity
        Tube_model
        Num_turns_spiral
        Num_points_spiral
        bool_3D_lines
        bool_overlay_cavity
    end
    
    methods (Access = public)
        
        function Change_figures(app)
            temp_value_density = app.DensitySlider.Value;
            temp_value_multiplication = app.SizeMultiplicationSlider.Value;
            temp_value_depth = app.CavityDepthSlider.Value;
            
            Num_turns = app.Num_turns_spiral;
            Num_points = app.Num_points_spiral;
            value_spacing = app.SSF;
            
            
            clear value_density value_multiplication value_depth

            if (1.5 <= temp_value_density) && (temp_value_density < (2.25+1.5)/2)
                value_density = 1.5;
            elseif ((2.25 + 1.5)/2 <= temp_value_density) && (temp_value_density < (2.25+3)/2)
                value_density = 2.25;
            elseif ((2.25+3)/2 <= temp_value_density) && (temp_value_density <= 3)
                value_density = 3;
            end

            
            if 1 <= temp_value_multiplication && temp_value_multiplication < 1.5
                value_multiplication = 1;
            elseif 1.5 <= temp_value_multiplication && temp_value_multiplication < 3.5
                value_multiplication = 2;
            elseif 3.5 <= temp_value_multiplication && temp_value_multiplication < 7.5
                value_multiplication = 5;
            elseif 7.5 <= temp_value_multiplication && temp_value_multiplication <= 10
                value_multiplication = 10;
            end
            
            if 10 <= temp_value_depth && temp_value_depth < 15
                value_depth = 10;
            elseif 15 <= temp_value_depth && temp_value_depth < 30
                value_depth = 20;
            elseif 30 <= temp_value_depth && temp_value_depth < 60
                value_depth = 40;
            elseif 60 <= temp_value_depth && temp_value_depth <= 80
                value_depth = 80;
            end
            
            if app.bool_use_squares == true
                Result_file_X = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_SSF_',num2str(value_spacing),'_X.mat'];
                Result_file_Y = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_SSF_',num2str(value_spacing),'_Y.mat'];
            else
                Result_file_X = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_SSF_1_X.mat'];
                Result_file_Y = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_SSF_1_Y.mat'];
            end
            X = importdata(Result_file_X);
            Y = importdata(Result_file_Y);
            
            if app.bool_use_squares == true
                Result_file_Z = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_MD_',num2str(value_density),'_CD_',num2str(value_depth),'_SSF_',num2str(value_spacing),'_Z.mat'];
            else
                Result_file_Z = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_MD_',num2str(value_density),'_CD_',num2str(value_depth),'_NR_',num2str(Num_turns),'_NP_',num2str(Num_points),'_spiral.mat'];
            end
            Z = importdata(Result_file_Z);   
            Z_int = inpaint_nans(Z,3);
            
            Contour_levels = 0.05:0.05:1.5;
            contour(app.UIAxes,X,Y,Z_int,Contour_levels,'ShowText','on','LabelSpacing',144,'LineWidth',3);
            
            if app.bool_overlay_cavity == true
                hold(app.UIAxes, 'on')
                Lava_tube = 'LAGOS';
                File_name = ['.\Data Analysis\Outline_',Lava_tube,'.mat'];
                Outline = importdata(File_name);
                scatter(app.UIAxes,value_multiplication*Outline(:,1),value_multiplication*Outline(:,2),'k');
                hold(app.UIAxes, 'off')
            else
                hold(app.UIAxes, 'off')
%                 clear app.Plot_cavity
%                 set(app.UIAxes,app.Plot_cavity,'Visible','off')
            end
            
            if app.bool_use_squares == true
                Result_file_3D = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_MD_',num2str(value_density),'_CD_',num2str(value_depth),'_SSF_',num2str(value_spacing),'_3D.mat'];
            else
                Result_file_3D = ['.\Database generation\Results\LAGOS_MM_',num2str(value_multiplication),'_MD_',num2str(value_density),'_CD_',num2str(value_depth),'_NR_',num2str(Num_turns),'_NP_',num2str(Num_points),'_spiral_3D.mat'];
            end
            
            Three_d = importdata(Result_file_3D);
            
            tri = delaunay(Three_d(:,1), Three_d(:,2));
            trisurf(tri, Three_d(:,1), Three_d(:,2), Three_d(:,3), 'Parent', app.UIAxes4)
            c=colorbar(app.UIAxes4);
            c.Label.String = 'Gravity Difference [mGal]';
            c.Label.Interpreter = 'latex';
            c.Label.FontSize = 11;
            
            app.UIAxes4.ZLim = [0.0 0.25*value_multiplication];
%             set(app.UIAxes4, 'CameraPosition', value_multiplication*[400 -100 0.2]);
            title(app.UIAxes4,'3D view gravity signal')
            if app.bool_3D_lines == true
                shading(app.UIAxes4, 'interp')
            end
            
            Lava_tube = 'LAGOS';
            File_name = ['.\Data Analysis\Depth_profile_',Lava_tube,'.mat'];
            Depth_profile = importdata(File_name);
            Plot_depth = plot(app.UIAxes3,value_multiplication*Depth_profile(:,1),value_multiplication*Depth_profile(:,2)-value_depth);   
            
            app.UIAxes3.YLim = [-150*value_multiplication 0];
            
            app.UIAxes2.cla
            hold(app.UIAxes2, 'on')
            
            if app.bool_use_squares == true
                for a=1:length(X(1,:))
                    scatter(app.UIAxes2,X(:,1),Y(:,a),'filled','r')
                end
            else
                scatter(app.UIAxes2,Three_d(:,1),Three_d(:,2),'filled','r')
            end
            hold(app.UIAxes2, 'off')
%             app.UIAxes3.YScale = 'log';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SSF = 5; % Station resolution setting
            app.StationSpacingDropDown.Value = 'x5';
            app.NumberofturnsDropDown.Value = '2';
            app.AsquaregridCheckBox.Value = true;
            app.AspiralCheckBox.Value = false;
            app.bool_use_squares = true;
            app.bool_use_spiral = false;
            app.OverlayCavityCheckBox.Value = false;
            app.SizeMultiplicationSlider.Value = 1;
            app.CavityDepthSlider.Value = 10;
            app.DensitySlider.Value = 1.5;
            app.Tube_model = 'LAGOS';
            app.LAGOSCheckBox.Value = true;
            app.Num_turns_spiral = 2;
            app.Num_points_spiral = 25;
            Change_figures(app);
        end

        % Value changed function: DensitySlider
        function DensitySliderValueChanged(app, event)
            Change_figures(app)
        end

        % Value changed function: OverlayCavityCheckBox
        function OverlayCavityCheckBoxValueChanged(app, event)
            value = app.OverlayCavityCheckBox.Value;
            app.bool_overlay_cavity = value;
            Change_figures(app)
        end

        % Value changed function: SizeMultiplicationSlider
        function SizeMultiplicationSliderValueChanged(app, event)
            Change_figures(app)
        end

        % Value changed function: CavityDepthSlider
        function CavityDepthSliderValueChanged(app, event)
            Change_figures(app)
        end

        % Value changed function: AsquaregridCheckBox
        function AsquaregridCheckBoxValueChanged(app, event)
            value_square_grid = app.AsquaregridCheckBox.Value;
            if value_square_grid==true
                
                app.bool_use_squares = true;
                app.AspiralCheckBox.Value = false;
                app.bool_use_spiral = false;
            end
            Change_figures(app);
        end

        % Value changed function: AspiralCheckBox
        function AspiralCheckBoxValueChanged(app, event)
            value_spiral = app.AspiralCheckBox.Value;
            if value_spiral ==true
                app.bool_use_spiral = true;
                app.AsquaregridCheckBox.Value = false;
                app.bool_use_squares = false;
            end
            Change_figures(app);
        end

        % Value changed function: StationSpacingDropDown
        function StationSpacingDropDownValueChanged(app, event)
            value = app.StationSpacingDropDown.Value;
            if app.bool_use_squares == true
                if strcmp(value,'x1')
                    app.SSF = 1;
                elseif strcmp(value,'x2')
                    app.SSF = 2;
                elseif strcmp(value,'x3')
                    app.SSF = 3;
                elseif strcmp(value,'x4')
                    app.SSF = 4;
                elseif strcmp(value,'x5')
                    app.SSF = 5;
                elseif strcmp(value,'x10')
                    app.SSF = 10;
                elseif strcmp(value,'x50')
                    app.SSF = 50;
                end            
            end
            Change_figures(app)
        end

        % Value changed function: NumberofturnsDropDown
        function NumberofturnsDropDownValueChanged(app, event)
            value = app.NumberofturnsDropDown.Value;
            
            if app.bool_use_spiral == true
                if strcmp(value,'2')
                    app.Num_turns_spiral = 2;
                elseif strcmp(value,'3')
                    app.Num_turns_spiral = 3;
                elseif strcmp(value,'4')
                    app.Num_turns_spiral = 4;
                elseif strcmp(value,'5')
                    app.Num_turns_spiral = 5;
                elseif strcmp(value,'6')
                    app.Num_turns_spiral = 6;
                end            
            end
            
            Change_figures(app)
        end

        % Value changed function: LAGOSCheckBox
        function LAGOSCheckBoxValueChanged(app, event)
            value = app.LAGOSCheckBox.Value;
            if value == true
                app.Tube_model='LAGOS';
                
                app.ETNACheckBox.Value = false;
                app.JAMEOCheckBox.Value = false;
                app.GALACheckBox.Value = false;
                Change_figures(app);
            end 
        end

        % Value changed function: GALACheckBox
        function GALACheckBoxValueChanged(app, event)
            value = app.GALACheckBox.Value;
            if value == true
                app.Tube_model='GALA';
                
                app.ETNACheckBox.Value = false;
                app.JAMEOCheckBox.Value = false;
                app.LAGOSCheckBox.Value = false;
                Change_figures(app);
            end
        end

        % Value changed function: JAMEOCheckBox
        function JAMEOCheckBoxValueChanged(app, event)
            value = app.JAMEOCheckBox.Value;
            if value == true
                app.Tube_model='JAMEO';
                
                app.ETNACheckBox.Value = false;
                app.GALACheckBox.Value = false;
                app.LAGOSCheckBox.Value = false;
                Change_figures(app);
            end
        end

        % Value changed function: ETNACheckBox
        function ETNACheckBoxValueChanged(app, event)
            value = app.ETNACheckBox.Value;
            if value == true
                app.Tube_model='ETNA';
                
                app.LAGOSCheckBox.Value = false;
                app.JAMEOCheckBox.Value = false;
                app.GALACheckBox.Value = false;
                Change_figures(app);
            end
        end

        % Value changed function: NumberofpointsDropDown
        function NumberofpointsDropDownValueChanged(app, event)
            value = app.NumberofpointsDropDown.Value;
            
            if app.bool_use_spiral == true
                app.Num_points_spiral = str2num(value);
                
%                 if strcmp(value,'25')
%                     app.Num_points_spiral = 25;
%                 elseif strcmp(value,'50')
%                     app.Num_points_spiral = 50;
%                 elseif strcmp(value,'100')
%                     app.Num_points_spiral = 100;
%                 elseif strcmp(value,'150')
%                     app.Num_points_spiral = 150;
%                 elseif strcmp(value,'250')
%                     app.Num_points_spiral = 250;
%                 end            
            end
            Change_figures(app)
        end

        % Value changed function: NolinesCheckBox
        function NolinesCheckBoxValueChanged(app, event)
            value = app.NolinesCheckBox.Value;
            
            app.bool_3D_lines = value;
            
            Change_figures(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Note: all gravity values are in mGal';
            app.DensityLabel = uilabel(app.LeftPanel);
            app.DensityLabel.Text = 'Density';
            app.DensitySlider = uislider(app.LeftPanel);
            app.DensitySlider.Limits = [1.5 3];
            app.DensitySlider.MajorTicks = [1.5 2.25 3];
            app.DensitySlider.MajorTickLabels = {'1.5', '2.25', '3.0'};
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @DensitySliderValueChanged, true);
            app.DensitySlider.MinorTicks = [];
            app.DensitySlider.Value = 1.5;
            app.OverlayCavityCheckBox = uicheckbox(app.LeftPanel);
            app.OverlayCavityCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCavityCheckBoxValueChanged, true);
            app.OverlayCavityCheckBox.Text = 'Overlay Cavity';
            app.SizeMultiplicationLabel = uilabel(app.LeftPanel);
            app.SizeMultiplicationLabel.Text = {'Size '; 'Multiplication'};
            app.SizeMultiplicationSlider = uislider(app.LeftPanel);
            app.SizeMultiplicationSlider.Limits = [1 10];
            app.SizeMultiplicationSlider.MajorTicks = [1 2 5 10];
            app.SizeMultiplicationSlider.ValueChangedFcn = createCallbackFcn(app, @SizeMultiplicationSliderValueChanged, true);
            app.SizeMultiplicationSlider.MinorTicks = [];
            app.SizeMultiplicationSlider.Value = 1;
            app.CavityDepthLabel = uilabel(app.LeftPanel);
            app.CavityDepthLabel.Text = 'Cavity Depth';
            app.CavityDepthSlider = uislider(app.LeftPanel);
            app.CavityDepthSlider.Limits = [10 80];
            app.CavityDepthSlider.MajorTicks = [10 20 40 80];
            app.CavityDepthSlider.ValueChangedFcn = createCallbackFcn(app, @CavityDepthSliderValueChanged, true);
            app.CavityDepthSlider.MinorTicks = [];
            app.CavityDepthSlider.Value = 10;
            app.StationSpacingDropDownLabel_2 = uilabel(app.LeftPanel);
            app.StationSpacingDropDownLabel_2.Text = 'Station settings. Choices are:';
            app.AsquaregridCheckBox = uicheckbox(app.LeftPanel);
            app.AsquaregridCheckBox.ValueChangedFcn = createCallbackFcn(app, @AsquaregridCheckBoxValueChanged, true);
            app.AsquaregridCheckBox.Text = 'A square grid';
            app.AspiralCheckBox = uicheckbox(app.LeftPanel);
            app.AspiralCheckBox.ValueChangedFcn = createCallbackFcn(app, @AspiralCheckBoxValueChanged, true);
            app.AspiralCheckBox.Text = 'A spiral';
            app.StationSpacingDropDownLabel_5 = uilabel(app.LeftPanel);
            app.StationSpacingDropDownLabel_5.Text = {'For the square grid the spacing is by'; 'default 2.5 m times Size Multiplication;'; 'this can be changed here'};
            app.StationSpacingDropDownLabel = uilabel(app.LeftPanel);
            app.StationSpacingDropDownLabel.Text = 'Station Spacing';
            app.StationSpacingDropDown = uidropdown(app.LeftPanel);
            app.StationSpacingDropDown.Items = {'x1', 'x2', 'x3', 'x4', 'x5', 'x10', 'x50'};
            app.StationSpacingDropDown.ValueChangedFcn = createCallbackFcn(app, @StationSpacingDropDownValueChanged, true);
            app.StationSpacingDropDown.Value = 'x1';
            app.StationSpacingDropDownLabel_6 = uilabel(app.LeftPanel);
            app.StationSpacingDropDownLabel_6.Text = {'For the spiral, you can change'; 'the number of turns;'};
            app.NumberofturnsDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofturnsDropDownLabel.Text = 'Number of turns';
            app.NumberofturnsDropDown = uidropdown(app.LeftPanel);
            app.NumberofturnsDropDown.Items = {'2', '3', '4', '5', '6'};
            app.NumberofturnsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofturnsDropDownValueChanged, true);
            app.NumberofturnsDropDown.Value = '2';
            app.LAGOSCheckBox = uicheckbox(app.LeftPanel);
            app.LAGOSCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAGOSCheckBoxValueChanged, true);
            app.LAGOSCheckBox.Text = 'LAGOS';
            app.GALACheckBox = uicheckbox(app.LeftPanel);
            app.GALACheckBox.ValueChangedFcn = createCallbackFcn(app, @GALACheckBoxValueChanged, true);
            app.GALACheckBox.Text = 'GALA';
            app.JAMEOCheckBox = uicheckbox(app.LeftPanel);
            app.JAMEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @JAMEOCheckBoxValueChanged, true);
            app.JAMEOCheckBox.Text = 'JAMEO';
            app.ETNACheckBox = uicheckbox(app.LeftPanel);
            app.ETNACheckBox.ValueChangedFcn = createCallbackFcn(app, @ETNACheckBoxValueChanged, true);
            app.ETNACheckBox.Text = 'ETNA';
            app.NumberofpointsDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.LeftPanel);
            app.NumberofpointsDropDown.Items = {'25', '50', '100', '150', '250'};
            app.NumberofpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofpointsDropDownValueChanged, true);
            app.NumberofpointsDropDown.Value = '25';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Difference gravity signal')
            xlabel(app.UIAxes, 'x-coordinate [m]')
            ylabel(app.UIAxes, 'y-coordinate [m]')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Depth profile cavity')
            xlabel(app.UIAxes3, 'Cavity length [m]')
            ylabel(app.UIAxes3, 'Average depth [m]')
            app.UIAxes3.YTick = [-1000 -950 -900 -850 -800 -750 -700 -650 -600 -550 -500 -450 -400 -350 -300 -250 -200 -150 -100 -50 0];
            app.UIAxes3.YTickLabel = {'-1000'; '-950'; '-900'; '-850'; '-800'; '-750'; '-700'; '-650'; '-600'; '-550'; '-500'; '-450'; '-400'; '-350'; '-300'; '-250'; '-200'; '-150'; '-100'; '-50'; '0'};
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, '3D view gravity signal')
            xlabel(app.UIAxes4, 'x-coordinate [m]')
            ylabel(app.UIAxes4, 'y-coordinate [m]')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Station Coordinates')
            xlabel(app.UIAxes2, 'x-coordinate [m]')
            ylabel(app.UIAxes2, 'y-coordinate [m]')
            app.NolinesCheckBox = uicheckbox(app.RightPanel);
            app.NolinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NolinesCheckBoxValueChanged, true);
            app.NolinesCheckBox.Text = 'No lines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_visualisation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end